classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        LSBPanel                      matlab.ui.container.Panel
        ImagehautePanel               matlab.ui.container.Panel
        Image                         matlab.ui.control.Image
        ImporterButton                matlab.ui.control.Button
        MessageenvoyerEditFieldLabel  matlab.ui.control.Label
        MessageenvoyerEditField       matlab.ui.control.EditField
        EnvoyerButton                 matlab.ui.control.Button
        StegoImagePanel               matlab.ui.container.Panel
        Image_2                       matlab.ui.control.Image
        TextAreaLabel                 matlab.ui.control.Label
        TextArea                      matlab.ui.control.TextArea
        AfficherButton                matlab.ui.control.Button
        MessagerecuEditFieldLabel     matlab.ui.control.Label
        MessagerecuEditField          matlab.ui.control.EditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ImporterButton
        function ImporterButtonPushed(app, event)
   
            [filename,filepath] = uigetfile({'*.jpg';'*.png';'*.bmp';'*.oct'}, 'Select File to Open');
            fullname = [filepath, filename];
            ImageFile = imread(fullname);
             app.Image.ImageSource = ImageFile;
        end

        % Button pushed function: EnvoyerButton
        function EnvoyerButtonPushed(app, event)
           messenv=app.MessageenvoyerEditField.Value;
           [msg stego]=insert(app.Image.ImageSource,messenv);
           app.Image_2.ImageSource=stego;
             app.MessagerecuEditField.Value=sprintf('%s',msg);
           
        end

        % Button pushed function: AfficherButton
        function AfficherButtonPushed(app, event)
            st=app.Image_2.ImageSource;
            app.MessagerecuEditField.value=sprintf('%.3f',mess);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LSBPanel = uipanel(app.UIFigure);
            app.LSBPanel.Title = 'LSB ';
            app.ImagehautePanel = uipanel(app.LSBPanel);
            app.ImagehautePanel.Title = 'Image haute';
            app.Image = uiimage(app.ImagehautePanel);
            app.ImporterButton = uibutton(app.LSBPanel, 'push');
            app.ImporterButton.ButtonPushedFcn = createCallbackFcn(app, @ImporterButtonPushed, true);
            app.ImporterButton.Text = 'Importer';
            app.MessageenvoyerEditFieldLabel = uilabel(app.LSBPanel);
            app.MessageenvoyerEditFieldLabel.Text = 'Message envoyer';
            app.MessageenvoyerEditField = uieditfield(app.LSBPanel, 'text');
            app.EnvoyerButton = uibutton(app.LSBPanel, 'push');
            app.EnvoyerButton.ButtonPushedFcn = createCallbackFcn(app, @EnvoyerButtonPushed, true);
            app.EnvoyerButton.Text = 'Envoyer';
            app.StegoImagePanel = uipanel(app.LSBPanel);
            app.StegoImagePanel.Title = 'Stego Image';
            app.Image_2 = uiimage(app.StegoImagePanel);
            app.TextAreaLabel = uilabel(app.StegoImagePanel);
            app.TextAreaLabel.Text = 'Text Area';
            app.TextArea = uitextarea(app.StegoImagePanel);
            app.AfficherButton = uibutton(app.LSBPanel, 'push');
            app.AfficherButton.ButtonPushedFcn = createCallbackFcn(app, @AfficherButtonPushed, true);
            app.AfficherButton.Text = 'Afficher';
            app.MessagerecuEditFieldLabel = uilabel(app.LSBPanel);
            app.MessagerecuEditFieldLabel.Text = 'Message recu';
            app.MessagerecuEditField = uieditfield(app.LSBPanel, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end